classdef project81852 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PrepareRTdataTab                matlab.ui.container.Tab
        RTcalculationPanel              matlab.ui.container.Panel
        locationButton                  matlab.ui.control.Button
        ChoosefoldercontainingG1bedsfromexperimentLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ChoosegenomeDropDownLabel       matlab.ui.control.Label
        ChoosegenomeDropDown            matlab.ui.control.DropDown
        CalculateButton                 matlab.ui.control.Button
        InterpolationsizeinkbEditFieldLabel  matlab.ui.control.Label
        InterpolationsizeinkbEditField  matlab.ui.control.NumericEditField
        SmoothingparameterEditFieldLabel  matlab.ui.control.Label
        SmoothingparameterEditField     matlab.ui.control.NumericEditField
        min_lenEditFieldLabel           matlab.ui.control.Label
        min_lenEditField                matlab.ui.control.NumericEditField
        ExpnameEditFieldLabel           matlab.ui.control.Label
        ExpnameEditField                matlab.ui.control.EditField
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        BrowseRTresultsTab              matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        ExporttoexcelButton             matlab.ui.control.Button
        AvailableExperimentsLabel       matlab.ui.control.Label
        AvailableExperimentsListBox     matlab.ui.control.ListBox
        Label_5                         matlab.ui.control.Label
        RefreshButton_2                 matlab.ui.control.Button
        PrepareDifRegionsTab            matlab.ui.container.Tab
        makedifferentregionsButton      matlab.ui.control.Button
        MinCutoffEditFieldLabel         matlab.ui.control.Label
        MinCutoffEditField              matlab.ui.control.NumericEditField
        UITable2_2                      matlab.ui.control.Table
        ChooserelevantexperimentsLabel_2  matlab.ui.control.Label
        Labelgroup1and2                 matlab.ui.control.Label
        UITable3_2                      matlab.ui.control.Table
        RefreshButton_3                 matlab.ui.control.Button
        outputnameEditFieldLabel        matlab.ui.control.Label
        outputnameEditField             matlab.ui.control.EditField
        pvcutoffEditFieldLabel          matlab.ui.control.Label
        pvcutoffEditField               matlab.ui.control.NumericEditField
        ConsecutiverunsEditFieldLabel   matlab.ui.control.Label
        ConsecutiverunsEditField        matlab.ui.control.NumericEditField
        Label_6                         matlab.ui.control.Label
        BrowsedifregionsTab             matlab.ui.container.Tab
        UITable_2                       matlab.ui.control.Table
        ExporttoexcelButton_2           matlab.ui.control.Button
        AvailableResultsListBoxLabel    matlab.ui.control.Label
        AvailableResultsListBox         matlab.ui.control.ListBox
        RefreshButton_4                 matlab.ui.control.Button
        Label_7                         matlab.ui.control.Label
        ShowRTmapsTab                   matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        ChromosomeDropDownLabel         matlab.ui.control.Label
        ChromosomeDropDown              matlab.ui.control.DropDown
        UITable2                        matlab.ui.control.Table
        ChooserelevantexperimentsLabel  matlab.ui.control.Label
        ChooserelevantsamplesLabel_2    matlab.ui.control.Label
        UITable3                        matlab.ui.control.Table
        RefreshButton                   matlab.ui.control.Button
        ShowdifferentregionsButton      matlab.ui.control.Button
        ClearplotButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        tableToPlot % rt plot data for access by multiple functions
        cur_tableToPlot % rt plot data of rel chro
        cur_dir %the location of this script
    end
    
    methods (Access = private)
        
        function data = makeSampleTable(app,indices,uitab)
            indices=indices.';
            %for any indeces chosen we should import table and get col names
            data = array2table(zeros(0,3),'VariableNames',{'exp','samp','color'}) ;
            full_rt_table=array2table(zeros(0,3),'VariableNames',{'loc','RT','chr'});
            %loop through exps
            for ind=indices
                exp=uitab{ind(1),ind(2)};
                table=load(strcat(app.cur_dir,"\profiles\",exp));
                table=table.full_table;
                names=table.Properties.VariableNames;
                rel_ind=contains(names,'RT');
                %loop through sample names
                for samp=names(rel_ind)
                    datanew = array2table([exp samp 'None'],'VariableNames',{'exp','samp','color'}) ;
                    data = [data ; datanew];
                    newname=strcat(samp,exp);
                    newname=newname{1};
                    table.Properties.VariableNames{samp} = erase(newname,'.');
                end
                if height(full_rt_table)<5
                    full_rt_table=table;
                else
                    full_rt_table=innerjoin(full_rt_table,table);
                end
            end
            app.tableToPlot=full_rt_table;    %save all relevant rt data as a variable        
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %get the directory to save and access files later
            path=mfilename('fullpath');
            cutoff=find(path == '\', 1, 'last');
            app.cur_dir=path(1:cutoff-1);
        
        
            a=dir(strcat(app.cur_dir,"\profiles\"));                         % Obtains the contents of the selected path.
            b={a(:).name}';                      % Gets the name of the files/folders of the contents and stores them appropriately in a cell array
            b(ismember(b,{'.','..'})) = [];      % Removes unnecessary '.' and '..' results from the display.
            
            app.AvailableExperimentsListBox.Items=b;  
            %for column in b if rt in column then it is a sample
            %list col name and item from b
            
            app.UITable2.Data=b;
            app.UITable2_2.Data=b;
            %set(t,'ColumnEditable',true(1,3))
            
            a=dir(strcat(app.cur_dir,"\dif region files\"));                         % Obtains the contents of the selected path.
            b={a(:).name}';                      % Gets the name of the files/folders of the contents and stores them appropriately in a cell array
            b(ismember(b,{'.','..'})) = [];      % Removes unnecessary '.' and '..' results from the display.
            
            app.AvailableResultsListBox.Items=b;  


        end

        % Button pushed function: locationButton
        function locationButtonPushed(app, event)
            dir=uigetdir();
            dir=convertCharsToStrings(dir);
            app.Label_3.Text=dir;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.Label_4.Text="running";
            run_RT(app.Label_3.Text,app.InterpolationsizeinkbEditField.Value,app.SmoothingparameterEditField.Value,app.min_lenEditField.Value,app.ChoosegenomeDropDown.Value,app.ExpnameEditField.Value,app.cur_dir);
            app.Label_4.Text="finished";

        end

        % Value changed function: AvailableExperimentsListBox
        function AvailableExperimentsListBoxValueChanged(app, event)
            %load the chosen data and display
            value = app.AvailableExperimentsListBox.Value;
            table=load(strcat(app.cur_dir,"\profiles\",value));
            table=table.full_table;
            app.UITable.Data=table;
            app.UITable.ColumnName=table.Properties.VariableNames;
            app.Label_5.Text=value;
        end

        % Button pushed function: ExporttoexcelButton
        function ExporttoexcelButtonPushed(app, event)
            dir=uigetdir();
            writetable(app.UITable.Data,strcat(dir,"\",app.AvailableExperimentsListBox.Value,".xls"));
        end

        % Value changed function: ChromosomeDropDown
        function ChromosomeDropDownValueChanged(app, event)
            %filter to the desired chromsome data
            value = app.ChromosomeDropDown.Value;
            label=strcat('loc on chr',value);
            app.UIAxes.XLabel.String =label;
            chr=strcat("chr",value);
            rel_indeces=app.tableToPlot.chr==chr;
            app.cur_tableToPlot=app.tableToPlot(rel_indeces,:);
            cla(app.UIAxes)
            
        end

        % Cell selection callback: UITable2
        function UITable2CellSelection(app, event)
            %use indices chosen to get exp names
            indices = event.Indices;
            data=app.makeSampleTable(indices,app.UITable2.Data);
            
            chr=strcat("chr",'1');
            rel_indeces=app.tableToPlot.chr==chr;
            app.cur_tableToPlot=app.tableToPlot(rel_indeces,:);

            %these get passed back to the samples table
            app.UITable3.Data=data;
        end

        % Cell edit callback: UITable3
        function UITable3CellEdit(app, event)
            %when volors are written in plot the sample
            indices = event.Indices;
            newData = event.NewData;
            chosenRow=indices(1);
            exp_name=app.UITable3.Data{chosenRow,1}; %get relevant exp
            samp_name=app.UITable3.Data{chosenRow,2}; %get relevant sample
            ycol=erase(strcat(samp_name,exp_name),'.');
            ycol=ycol{1};
            Yvals=app.cur_tableToPlot.(ycol);
            Xvals=app.cur_tableToPlot.loc;
            plot(app.UIAxes,Xvals,Yvals,'color',newData); %plot this data in chosen color
            hold(app.UIAxes,'on');
        end

        % Button pushed function: RefreshButton, RefreshButton_2, 
        % RefreshButton_3, RefreshButton_4
        function RefreshButtonPushed(app, event)
            startupFcn(app);
        end

        % Cell selection callback: UITable2_2
        function UITable2_2CellSelection(app, event)
            indices = event.Indices;
            data=app.makeSampleTable(indices,app.UITable2.Data);
            data.Properties.VariableNames{'color'}='group'; %reuse the table made in the plotting function but instead of color, user will choose group
            app.UITable3_2.Data=data;
        end

        % Callback function
        function ConsecutiverunsCheckBoxValueChanged(app, event)
            %this is no longer relevant! saving in case later we want to
            %change this
            value = app.ConsecutiverunsCheckBox.Value;
            cur_state=app.lenientpvcutoffEditField.Visible;
            if strcmp(cur_state,'on')
                switchTo='off';
                app.lenientpvcutoffEditField.Value=0;
                app.runlengthEditField.Value=0;
            else
                switchTo='on';
            end
            app.lenientpvcutoffEditField.Visible=switchTo;
            app.lenientpvcutoffEditFieldLabel.Visible=switchTo;
            app.runlengthEditFieldLabel.Visible=switchTo;
            app.runlengthEditField.Visible=switchTo;
        end

        % Button pushed function: makedifferentregionsButton
        function makedifferentregionsButtonPushed(app, event)
            app.Label_6.Text="running";
            %get groups
            group1=app.UITable3_2.Data(app.UITable3_2.Data.group=="1",:);
            group1_names=strings(1,height(group1));
            for i=1:height(group1)
                newname=strcat(group1{i,2},group1{i,1});
                newname=newname{1};
                newname=erase(newname,'.');
                group1_names(i)=newname;
            end         
            group2=app.UITable3_2.Data(app.UITable3_2.Data.group=="2",:);
            group2_names=strings(1,height(group2));
            for i=1:height(group2)
                newname=strcat(group2{i,2},group2{i,1});
                newname=newname{1};
                newname=erase(newname,'.');
                group2_names(i)=newname;
            end 

            %run script            
            makeDifRegions(group1_names,group2_names,app.tableToPlot,app.MinCutoffEditField.Value,app.outputnameEditField.Value,app.pvcutoffEditField.Value,app.ConsecutiverunsEditField.Value,app.cur_dir);
            app.Label_6.Text="done";
            
            
        end

        % Button pushed function: ExporttoexcelButton_2
        function ExporttoexcelButton_2Pushed(app, event)
            dir=uigetdir();
            writetable(app.UITable_2.Data,strcat(dir,"/diff_region_",app.AvailableResultsListBox.Value,".xls"));
        end

        % Value changed function: AvailableResultsListBox
        function AvailableResultsListBoxValueChanged(app, event)
            value = app.AvailableResultsListBox.Value;
            table=load(strcat(app.cur_dir,"\dif region files\",value));
            table=table.table;
            app.UITable_2.Data=table;
            app.UITable_2.ColumnName=table.Properties.VariableNames;
            app.Label_7.Text=value;
        end

        % Callback function
        function ShowdifferentregionsCheckBoxValueChanged(app, event)
            %this is no longer relevant! saving in case later we want to
            %change this
            value = app.ShowdifferentregionsCheckBox.Value;
            file = uigetfile(fullfile(app.cur_dir,'\dif region files\','*.mat'),'select file');
            table=load(strcat(app.cur_dir,"\dif region files\",file));
            table=table.table;
            table=table(table.chr==strcat("chr",app.ChromosomeDropDown.Value),:);
            table=table(table.pass_all==1,:);
            prev_loc=0;
            alpha=.1;
            for i=1:length(table.loc)
                loc=table.loc(i);
                if prev_loc==0
                    prev_loc=loc;
                   
                else
                    patch(app.UIAxes,[prev_loc,prev_loc,loc,loc],[-2.5,2.5,2.5,-2.5],'black','FaceAlpha',alpha,'EdgeColor','none');
                end
            end


        end

        % Button pushed function: ShowdifferentregionsButton
        function ShowdifferentregionsButtonPushed(app, event)
            %user inputs which dif region file to use
            file = uigetfile(fullfile(app.cur_dir,'\dif region files\','*.mat'),'select file');
            table=load(strcat(app.cur_dir,"\dif region files\",file));
            table=table.table;
            table=table(table.chr==strcat("chr",app.ChromosomeDropDown.Value),:);
            table=table(table.pass_all==1,:);
            prev_loc=0;
            alpha=.1;
            %for region in dif region file that is sig, shade the plot
            for i=1:length(table.loc)
                loc=table.loc(i);
                if prev_loc==0
                    prev_loc=loc;
                   
                else
                    patch(app.UIAxes,[prev_loc,prev_loc,loc,loc],[-2.5,2.5,2.5,-2.5],'black','FaceAlpha',alpha,'EdgeColor','none');
                end
            end
        end

        % Button pushed function: ClearplotButton
        function ClearplotButtonPushed(app, event)
            cla(app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PrepareRTdataTab = uitab(app.TabGroup);
            app.PrepareRTdataTab.Title = 'Prepare RT data';
            app.RTcalculationPanel = uipanel(app.PrepareRTdataTab);
            app.RTcalculationPanel.Title = 'RT calculation';
            app.locationButton = uibutton(app.RTcalculationPanel, 'push');
            app.locationButton.ButtonPushedFcn = createCallbackFcn(app, @locationButtonPushed, true);
            app.locationButton.Text = 'location';
            app.ChoosefoldercontainingG1bedsfromexperimentLabel = uilabel(app.RTcalculationPanel);
            app.ChoosefoldercontainingG1bedsfromexperimentLabel.Text = {'Choose folder containing G1 bed folder and '; 'S beds folder from experiment'; ''};
            app.Label = uilabel(app.RTcalculationPanel);
            app.Label.Text = {'To calculate RT all bed files from a'; ' given experiment must be stored in '; 'one folder with no other files'};
            app.ChoosegenomeDropDownLabel = uilabel(app.RTcalculationPanel);
            app.ChoosegenomeDropDownLabel.Text = 'Choose genome';
            app.ChoosegenomeDropDown = uidropdown(app.RTcalculationPanel);
            app.ChoosegenomeDropDown.Items = {'hg19', 'hg38', 'mm9', 'mm10', 'manual'};
            app.ChoosegenomeDropDown.Value = 'hg19';
            app.CalculateButton = uibutton(app.RTcalculationPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.InterpolationsizeinkbEditFieldLabel = uilabel(app.RTcalculationPanel);
            app.InterpolationsizeinkbEditFieldLabel.Text = 'Interpolation size (in kb)';
            app.InterpolationsizeinkbEditField = uieditfield(app.RTcalculationPanel, 'numeric');
            app.InterpolationsizeinkbEditField.Value = 100;
            app.SmoothingparameterEditFieldLabel = uilabel(app.RTcalculationPanel);
            app.SmoothingparameterEditFieldLabel.Text = 'Smoothing parameter';
            app.SmoothingparameterEditField = uieditfield(app.RTcalculationPanel, 'numeric');
            app.SmoothingparameterEditField.Value = 1e-16;
            app.min_lenEditFieldLabel = uilabel(app.RTcalculationPanel);
            app.min_lenEditFieldLabel.Text = 'min_len';
            app.min_lenEditField = uieditfield(app.RTcalculationPanel, 'numeric');
            app.min_lenEditField.Value = 15;
            app.ExpnameEditFieldLabel = uilabel(app.RTcalculationPanel);
            app.ExpnameEditFieldLabel.Text = 'Exp name';
            app.ExpnameEditField = uieditfield(app.RTcalculationPanel, 'text');
            app.Label_3 = uilabel(app.RTcalculationPanel);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.RTcalculationPanel);
            app.Label_4.Text = '';
            app.BrowseRTresultsTab = uitab(app.TabGroup);
            app.BrowseRTresultsTab.Title = 'Browse RT results';
            app.UITable = uitable(app.BrowseRTresultsTab);
            app.UITable.ColumnName = {'Chr'; 'loc'; 'RT'};
            app.UITable.RowName = {};
            app.ExporttoexcelButton = uibutton(app.BrowseRTresultsTab, 'push');
            app.ExporttoexcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoexcelButtonPushed, true);
            app.ExporttoexcelButton.Text = 'Export to excel';
            app.AvailableExperimentsLabel = uilabel(app.BrowseRTresultsTab);
            app.AvailableExperimentsLabel.Text = {'Available '; 'Experiments'};
            app.AvailableExperimentsListBox = uilistbox(app.BrowseRTresultsTab);
            app.AvailableExperimentsListBox.ValueChangedFcn = createCallbackFcn(app, @AvailableExperimentsListBoxValueChanged, true);
            app.Label_5 = uilabel(app.BrowseRTresultsTab);
            app.Label_5.Text = '';
            app.RefreshButton_2 = uibutton(app.BrowseRTresultsTab, 'push');
            app.RefreshButton_2.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton_2.Text = 'Refresh';
            app.PrepareDifRegionsTab = uitab(app.TabGroup);
            app.PrepareDifRegionsTab.Title = 'Prepare Dif Regions';
            app.makedifferentregionsButton = uibutton(app.PrepareDifRegionsTab, 'push');
            app.makedifferentregionsButton.ButtonPushedFcn = createCallbackFcn(app, @makedifferentregionsButtonPushed, true);
            app.makedifferentregionsButton.Text = 'make different regions';
            app.MinCutoffEditFieldLabel = uilabel(app.PrepareDifRegionsTab);
            app.MinCutoffEditFieldLabel.Text = 'Min Cutoff';
            app.MinCutoffEditField = uieditfield(app.PrepareDifRegionsTab, 'numeric');
            app.MinCutoffEditField.Value = 0.5;
            app.UITable2_2 = uitable(app.PrepareDifRegionsTab);
            app.UITable2_2.ColumnName = {'exps'};
            app.UITable2_2.RowName = {};
            app.UITable2_2.CellSelectionCallback = createCallbackFcn(app, @UITable2_2CellSelection, true);
            app.ChooserelevantexperimentsLabel_2 = uilabel(app.PrepareDifRegionsTab);
            app.ChooserelevantexperimentsLabel_2.Text = 'Choose relevant experiments';
            app.Labelgroup1and2 = uilabel(app.PrepareDifRegionsTab);
            app.Labelgroup1and2.Text = 'Label group 1 and 2';
            app.UITable3_2 = uitable(app.PrepareDifRegionsTab);
            app.UITable3_2.ColumnName = {'exp'; 'sample'; 'group'};
            app.UITable3_2.RowName = {};
            app.UITable3_2.ColumnEditable = [false false true];
            app.RefreshButton_3 = uibutton(app.PrepareDifRegionsTab, 'push');
            app.RefreshButton_3.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton_3.Text = 'Refresh';
            app.outputnameEditFieldLabel = uilabel(app.PrepareDifRegionsTab);
            app.outputnameEditFieldLabel.Text = 'output name';
            app.outputnameEditField = uieditfield(app.PrepareDifRegionsTab, 'text');
            app.pvcutoffEditFieldLabel = uilabel(app.PrepareDifRegionsTab);
            app.pvcutoffEditFieldLabel.Text = 'pv cutoff';
            app.pvcutoffEditField = uieditfield(app.PrepareDifRegionsTab, 'numeric');
            app.pvcutoffEditField.Value = 0.05;
            app.ConsecutiverunsEditFieldLabel = uilabel(app.PrepareDifRegionsTab);
            app.ConsecutiverunsEditFieldLabel.Text = 'Consecutive runs';
            app.ConsecutiverunsEditField = uieditfield(app.PrepareDifRegionsTab, 'numeric');
            app.ConsecutiverunsEditField.Value = 1;
            app.Label_6 = uilabel(app.PrepareDifRegionsTab);
            app.Label_6.Text = '';
            app.BrowsedifregionsTab = uitab(app.TabGroup);
            app.BrowsedifregionsTab.Title = 'Browse dif regions';
            app.UITable_2 = uitable(app.BrowsedifregionsTab);
            app.UITable_2.ColumnName = {'chr'; 'loc'; 'rt'};
            app.UITable_2.RowName = {};
            app.ExporttoexcelButton_2 = uibutton(app.BrowsedifregionsTab, 'push');
            app.ExporttoexcelButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExporttoexcelButton_2Pushed, true);
            app.ExporttoexcelButton_2.Text = 'Export to excel';
            app.AvailableResultsListBoxLabel = uilabel(app.BrowsedifregionsTab);
            app.AvailableResultsListBoxLabel.Text = {'Available '; 'Results'};
            app.AvailableResultsListBox = uilistbox(app.BrowsedifregionsTab);
            app.AvailableResultsListBox.ValueChangedFcn = createCallbackFcn(app, @AvailableResultsListBoxValueChanged, true);
            app.RefreshButton_4 = uibutton(app.BrowsedifregionsTab, 'push');
            app.RefreshButton_4.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton_4.Text = 'Refresh';
            app.Label_7 = uilabel(app.BrowsedifregionsTab);
            app.Label_7.Text = '';
            app.ShowRTmapsTab = uitab(app.TabGroup);
            app.ShowRTmapsTab.Title = 'Show RT maps';
            app.UIAxes = uiaxes(app.ShowRTmapsTab);
            title(app.UIAxes, 'RT plot')
            xlabel(app.UIAxes, 'loc on chr1')
            ylabel(app.UIAxes, 'RT value')
            app.ChromosomeDropDownLabel = uilabel(app.ShowRTmapsTab);
            app.ChromosomeDropDownLabel.Text = 'Chromosome';
            app.ChromosomeDropDown = uidropdown(app.ShowRTmapsTab);
            app.ChromosomeDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', 'X', 'Y'};
            app.ChromosomeDropDown.ValueChangedFcn = createCallbackFcn(app, @ChromosomeDropDownValueChanged, true);
            app.ChromosomeDropDown.Value = '1';
            app.UITable2 = uitable(app.ShowRTmapsTab);
            app.UITable2.ColumnName = {'exps'};
            app.UITable2.RowName = {};
            app.UITable2.CellSelectionCallback = createCallbackFcn(app, @UITable2CellSelection, true);
            app.ChooserelevantexperimentsLabel = uilabel(app.ShowRTmapsTab);
            app.ChooserelevantexperimentsLabel.Text = 'Choose relevant experiments';
            app.ChooserelevantsamplesLabel_2 = uilabel(app.ShowRTmapsTab);
            app.ChooserelevantsamplesLabel_2.Text = 'Choose relevant samples';
            app.UITable3 = uitable(app.ShowRTmapsTab);
            app.UITable3.ColumnName = {'exp'; 'sample'; 'color'};
            app.UITable3.RowName = {};
            app.UITable3.ColumnEditable = [false false true];
            app.UITable3.CellEditCallback = createCallbackFcn(app, @UITable3CellEdit, true);
            app.RefreshButton = uibutton(app.ShowRTmapsTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ShowdifferentregionsButton = uibutton(app.ShowRTmapsTab, 'push');
            app.ShowdifferentregionsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowdifferentregionsButtonPushed, true);
            app.ShowdifferentregionsButton.Text = 'Show different regions';
            app.ClearplotButton = uibutton(app.ShowRTmapsTab, 'push');
            app.ClearplotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearplotButtonPushed, true);
            app.ClearplotButton.Text = 'Clear plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project81852

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end